classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Joint6Slider        matlab.ui.control.Slider
        Joint6SliderLabel   matlab.ui.control.Label
        Joint5Slider        matlab.ui.control.Slider
        Joint5SliderLabel   matlab.ui.control.Label
        Joint4Slider        matlab.ui.control.Slider
        Joint4SliderLabel   matlab.ui.control.Label
        Joint3Slider        matlab.ui.control.Slider
        Joint3SliderLabel   matlab.ui.control.Label
        Joint2Slider        matlab.ui.control.Slider
        Joint2SliderLabel   matlab.ui.control.Label
        Joint1Slider        matlab.ui.control.Slider
        Joint1SliderLabel   matlab.ui.control.Label
        ZSpinner            matlab.ui.control.Spinner
        ZSpinnerLabel       matlab.ui.control.Label
        YSpinner            matlab.ui.control.Spinner
        YSpinnerLabel       matlab.ui.control.Label
        XSpinner            matlab.ui.control.Spinner
        XSpinnerLabel       matlab.ui.control.Label
        Z_DButton           matlab.ui.control.Button
        Z_UButton           matlab.ui.control.Button
        X_RButton           matlab.ui.control.Button
        X_LButton           matlab.ui.control.Button
        Y_BButton           matlab.ui.control.Button
        Y_FButton           matlab.ui.control.Button
        DoubleStopCheckBox  matlab.ui.control.CheckBox
        IgnitionCheckBox    matlab.ui.control.CheckBox
        StopButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        %  starts here, insert everything that we require.

        modelR3; % insert UR3 here

        % Insert second robot here
         

        % This section is for variables that we will constantly be using
        % throughout. 
        qI = [0,pi/2,0,0,pi/2,0]; %placeholder for q initiate
        q0 % used in later button pushing 
        qUR3 = [0,pi,pi/2,0,0,-pi]; %placeholder
        TMatrix % transformation matrix for XYZ
        qAngles % joint angles
        steps=20;

    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            
            app.modelR3 = UR3;
            app.modelR3.model.base = transl([0,0,0.5]); 
            app.modelR3.model.animate(app.qI);
            drawnow();
            hold on;   

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % enable the move button
            app.StartButton.Enable = true;
            
            % Sets up the joint sliders and the XYZ boxes to display
            % current positions
            app.TMatrix=(app.modelR3.model.fkine(app.modelR3.model.getpos()).T); %Get the X Y Z spinner values from this
            app.XSpinner.Value=app.TMatrix(1,4);
            app.YSpinner.Value=app.TMatrix(2,4);
            app.ZSpinner.Value=app.TMatrix(3,4);
            app.qAngles=rad2deg(app.modelR3.model.getpos()); % Get the joint angles from this
            app.Joint1Slider.Value=app.qAngles(1);
            app.Joint2Slider.Value=app.qAngles(2);
            app.Joint3Slider.Value=app.qAngles(3);
            app.Joint4Slider.Value=app.qAngles(4);
            app.Joint5Slider.Value=app.qAngles(5);
            app.Joint6Slider.Value=app.qAngles(6);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % If the stop button is pushed, a secondary checkbox will be
            % activated whilst turning off the 'ignition'

            app.DoubleStopCheckBox.Value=1;
            app.IgnitionCheckBox.Value=0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

                        sT = app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0.1,0);
                        sQ = app.modelR3.model.ikcon(sT);
                        qMatrix=jtraj(sQ,app.qUR3,50);

                        % 
                        for robotStepIndex = 1:size(qMatrix,1)

                            if app.DoubleStopCheckBox.Value==0 && app.IgnitionCheckBox.Value==1
                            % Will run according to course as the button is
                            % pressed. Change accordingly.
                            app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                            app.TMatrix=(app.modelR3.model.fkine(app.modelR3.model.getpos()).T);
                            app.XSpinner.Value=app.TMatrix(1,4);
                            app.YSpinner.Value=app.TMatrix(2,4);
                            app.ZSpinner.Value=app.TMatrix(3,4);
                            app.qAngles=rad2deg(app.modelR3.model.getpos())
                            app.Joint1Slider.Value=app.qAngles(1);
                            app.Joint2Slider.Value=app.qAngles(2);
                            app.Joint3Slider.Value=app.qAngles(3);
                            app.Joint4Slider.Value=app.qAngles(4);
                            app.Joint5Slider.Value=app.qAngles(5);
                            app.Joint6Slider.Value=app.qAngles(6);
                            drawnow();

                            % If the stop button is pressed mid program, it
                            % will stop and display an error to restart.
                            elseif app.DoubleStopCheckBox.Value==1
                                msg='Something is wrong! Please restart the ignition and clear the emergency stop features!';
                                error(msg)
                            end
                        end

        end

        % Button pushed function: Y_FButton
        function Y_FButtonPushed(app, event)
            % This button will move the robot in the positive Y direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); 
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0.1,0));


            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));

                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);

                drawnow();
            end
        end

        % Button pushed function: X_RButton
        function X_RButtonPushed(app, event)
            % This button will move the robot in the positive X direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); % = xMatrix(:,end)
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0.1,0,0));
            
            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                
                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);
                drawnow();

            end
        end

        % Button pushed function: X_LButton
        function X_LButtonPushed(app, event)
            % This button will move the robot in the neagtive X direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); % = xMatrix(:,end)
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(-0.1,0,0));
            
            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                
                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);
                drawnow();

            end

        end

        % Button pushed function: Y_BButton
        function Y_BButtonPushed(app, event)
            % This button will move the robot in the negative Y direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); 
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,-0.1,0));


            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));

                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);

                drawnow();
            end
        end

        % Button pushed function: Z_DButton
        function Z_DButtonPushed(app, event)
            % This button will move the robot in the negative Z direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); 
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,-0.1));


            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));

                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);

                drawnow();
            end
        end

        % Button pushed function: Z_UButton
        function Z_UButtonPushed(app, event)
            % This button will move the robot in the positive Z direction
            
            tr1 = app.modelR3.model.fkine(app.modelR3.model.getpos());
            try app.q0 = qMatrix(:,end); 
            catch app.q0 = app.modelR3.model.ikcon(tr1);
            end
            tr2 = SE3(app.modelR3.model.fkine(app.modelR3.model.getpos()).T * transl(0,0,0.1));


            qMatrix = resolvedMotionRateControl(app.modelR3,tr1,tr2,app.q0,app.steps);
            app.TMatrix=(app.modelR3.model.fkine(qMatrix));
            app.qAngles=rad2deg(qMatrix);

            for robotStepIndex = 1:size(qMatrix,1)
                app.modelR3.model.animate(qMatrix(robotStepIndex,:));

                app.XSpinner.Value=app.TMatrix(1,robotStepIndex).t(1);
                app.YSpinner.Value=app.TMatrix(1,robotStepIndex).t(2);
                app.ZSpinner.Value=app.TMatrix(1,robotStepIndex).t(3);
                
                app.Joint1Slider.Value=app.qAngles(robotStepIndex,1);
                app.Joint2Slider.Value=app.qAngles(robotStepIndex,2);
                app.Joint3Slider.Value=app.qAngles(robotStepIndex,3);
                app.Joint4Slider.Value=app.qAngles(robotStepIndex,4);
                app.Joint5Slider.Value=app.qAngles(robotStepIndex,5);
                app.Joint6Slider.Value=app.qAngles(robotStepIndex,6);

                drawnow();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IgnitionCheckBox = uicheckbox(app.UIFigure);
            app.IgnitionCheckBox.Text = 'Ignition';
            app.DoubleStopCheckBox = uicheckbox(app.UIFigure);
            app.DoubleStopCheckBox.Text = 'Double Stop';
            app.Y_FButton = uibutton(app.UIFigure, 'push');
            app.Y_FButton.ButtonPushedFcn = createCallbackFcn(app, @Y_FButtonPushed, true);
            app.Y_FButton.Text = 'Y_F';
            app.Y_BButton = uibutton(app.UIFigure, 'push');
            app.Y_BButton.ButtonPushedFcn = createCallbackFcn(app, @Y_BButtonPushed, true);
            app.Y_BButton.Text = 'Y_B';
            app.X_LButton = uibutton(app.UIFigure, 'push');
            app.X_LButton.ButtonPushedFcn = createCallbackFcn(app, @X_LButtonPushed, true);
            app.X_LButton.Text = 'X_L';
            app.X_RButton = uibutton(app.UIFigure, 'push');
            app.X_RButton.ButtonPushedFcn = createCallbackFcn(app, @X_RButtonPushed, true);
            app.X_RButton.Text = 'X_R';
            app.Z_UButton = uibutton(app.UIFigure, 'push');
            app.Z_UButton.ButtonPushedFcn = createCallbackFcn(app, @Z_UButtonPushed, true);
            app.Z_UButton.Text = 'Z_U';
            app.Z_DButton = uibutton(app.UIFigure, 'push');
            app.Z_DButton.ButtonPushedFcn = createCallbackFcn(app, @Z_DButtonPushed, true);
            app.Z_DButton.Text = 'Z_D';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-361 361];
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-361 361];
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-361 361];
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-361 361];
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-361 361];
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-361 361];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end